classdef Clicky_GUI_For_Chrissi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SinFitPlot                     matlab.ui.control.UIAxes
        EquiphotonFluxPlot             matlab.ui.control.UIAxes
        PowerAtEquiphotonFlux          matlab.ui.control.UIAxes
        DataTable                      matlab.ui.control.Table
        CMDSliderLabel                 matlab.ui.control.Label
        CMDSlider                      matlab.ui.control.Slider
        WavelengthSliderLabel          matlab.ui.control.Label
        WavelengthSlider               matlab.ui.control.Slider
        PhotonsPerPulseEditFieldLabel  matlab.ui.control.Label
        PhotonsPerPulseEditField       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = updatePlots(app)
            wavelength = app.WavelengthSlider.Value;
            CMD = app.CMDSlider.Value;
            
            [photon_flux_at_wavelength, equi_photon_cmd_value, unique_wavelengths, milliwatts_at_command_at_wavelength] = Compute_CMD_Values_Func(wavelength, CMD, app.SinFitPlot, app.EquiphotonFluxPlot, app.PowerAtEquiphotonFlux);
            
            app.DataTable.Data = [unique_wavelengths, equi_photon_cmd_value, milliwatts_at_command_at_wavelength];
            app.PhotonsPerPulseEditField.Value = photon_flux_at_wavelength;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CMDSlider
        function CMDSliderValueChanged(app, event)
            app.updatePlots();
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            app.WavelengthSlider.Value = round(app.WavelengthSlider.Value/10)*10;
            app.updatePlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SinFitPlot = uiaxes(app.UIFigure);
            title(app.SinFitPlot, 'Sine Fit Plot')
            xlabel(app.SinFitPlot, 'X')
            ylabel(app.SinFitPlot, 'Y')
            app.EquiphotonFluxPlot = uiaxes(app.UIFigure);
            title(app.EquiphotonFluxPlot, 'Equiphoton Flux')
            xlabel(app.EquiphotonFluxPlot, 'X')
            ylabel(app.EquiphotonFluxPlot, 'Y')
            app.PowerAtEquiphotonFlux = uiaxes(app.UIFigure);
            title(app.PowerAtEquiphotonFlux, 'Power at Equiphoton Flux')
            xlabel(app.PowerAtEquiphotonFlux, 'X')
            ylabel(app.PowerAtEquiphotonFlux, 'Y')
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'Wavelength'; 'CMD Value'; 'Power'};
            app.DataTable.RowName = {};
            app.CMDSliderLabel = uilabel(app.UIFigure);
            app.CMDSliderLabel.Text = 'CMD';
            app.CMDSlider = uislider(app.UIFigure);
            app.CMDSlider.Limits = [0 1];
            app.CMDSlider.ValueChangedFcn = createCallbackFcn(app, @CMDSliderValueChanged, true);
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [750 1080];
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.WavelengthSlider.Value = 800;
            app.PhotonsPerPulseEditFieldLabel = uilabel(app.UIFigure);
            app.PhotonsPerPulseEditFieldLabel.Text = 'Photons Per Pulse';
            app.PhotonsPerPulseEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clicky_GUI_For_Chrissi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end